classdef RGCMorphologyAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RGCMorphologyAnalyzerUIFigure   matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadskeletonimageMenu           matlab.ui.container.Menu
        LoadswcMenu                     matlab.ui.container.Menu
        LoadsurfacesMenu                matlab.ui.container.Menu
        LoadanalyzeddataMenu            matlab.ui.container.Menu
        SavesurfacesMenu                matlab.ui.container.Menu
        SaveanalyzeddataMenu            matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        AnalyzeMenu                     matlab.ui.container.Menu
        StratificationprofileMenu       matlab.ui.container.Menu
        ArborpropertiesMenu             matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        FirstSegSoma                    matlab.ui.control.CheckBox
        ZscalefactorEditField           matlab.ui.control.NumericEditField
        ZscalefactorEditFieldLabel      matlab.ui.control.Label
        SegmentLinesCheckBox            matlab.ui.control.CheckBox
        BistratifiedCheckBox            matlab.ui.control.CheckBox
        SurfName_EditField              matlab.ui.control.EditField
        DotsizeSlider                   matlab.ui.control.Slider
        DotsizeSliderLabel              matlab.ui.control.Label
        RemoveButton                    matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        ScalempixelLabel                matlab.ui.control.Label
        zEditField                      matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        xyEditField                     matlab.ui.control.NumericEditField
        xyEditFieldLabel                matlab.ui.control.Label
        SomacenterframeEditField        matlab.ui.control.NumericEditField
        SomacenterframeEditFieldLabel   matlab.ui.control.Label
        SmoothingwindowmSlider          matlab.ui.control.Slider
        SmoothingwindowmSliderLabel     matlab.ui.control.Label
        SurfacesListBox                 matlab.ui.control.ListBox
        SurfacesListBoxLabel            matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        ComputedistancestosurfButton    matlab.ui.control.Button
        OffsetEditField                 matlab.ui.control.NumericEditField
        OffsetmEditFieldLabel           matlab.ui.control.Label
        NormposEditField_upper          matlab.ui.control.NumericEditField
        NormposEditField_2Label         matlab.ui.control.Label
        NormposEditField_lower          matlab.ui.control.NumericEditField
        NormposEditFieldLabel           matlab.ui.control.Label
        UppersurfaceDropDown            matlab.ui.control.DropDown
        UppersurfaceDropDownLabel       matlab.ui.control.Label
        LowersurfaceDropDown            matlab.ui.control.DropDown
        LowersurfaceDropDownLabel       matlab.ui.control.Label
        DrawsurfaceonskeletonButton     matlab.ui.control.Button
        MakesurfaceButton               matlab.ui.control.Button
        SavepointsinselectedsurfButton  matlab.ui.control.Button
        ComputepointsdistancesButton    matlab.ui.control.Button
        SurfacesLabel                   matlab.ui.control.Label
        ClearsurfaceguidepointsButton   matlab.ui.control.Button
        SelectsurfaceguidepointsButton  matlab.ui.control.Button
        ListBox_surfaceSelector         matlab.ui.control.ListBox
        ReloadoriginalButton            matlab.ui.control.Button
        UnwarpButton                    matlab.ui.control.Button
        skel_axes                       matlab.ui.control.UIAxes
        surf_axes                       matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        ResultsTable                    matlab.ui.control.Table
        Strat_axes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        xy_res = 1;% microns per pixel
        z_res = 1; %microns per pixel
        skel %skeleton image
        w %image width (pixels)
        h %image height (pixels)
        Nframes %image N frames
        dotSize = 5; %for drawing skeleton
        filterWidth = 15; % filter width (microns)
        surfaceStartFrame = 1 % start frame
        skel_h = []; %handle to skeleton plot
        activeSurf = 'Dendrites_1' % active surface
        surfaces = {'Dendrites_1'}; %list of all surfaces
        skel_color_vec % vector indicator surface identity of each piece of dendrite
        colored_surfaces = {}; %cell array of surfaces with colored dendrite points
        %skel_pt_cloud = [] % point cloud object for skeleton
        dcm % datacursormode object
        surfaceMaps = {[]}; % Description
        nodes = []; % swc nodes
        edges = []; % swc edges
        radii = [];% swc radii
        nodes_flattened = []; % after unwarp
        edges_flattened = []; % after unwarp
        radii_flattened = []; % after unwarp
        lower_surface_z; %z value of lower surface in microns
        upper_surface_z; %z value of upper surface in microns
        lower_surf_norm_z; %normalized value for lower surface for strat. profile
        upper_surf_norm_z; %normalized value for upper surface for strat. profile
        strat_x % stratification x values
        strat_density %units of microns dendritic length?
        strat_y_norm %normalized density
        allXYpos_flattened = [];% from the stratification analyzer, needed for node analysis stuff
        allZpos_flattened = [];% from the stratification analyzer, needed for node analysis stuff
        arborStats % struct of arbor stats
        segmentLines = []; % handles for the line segments of the skeleton
        length_by_node % length in microns for each node
        last_path % Description
        ifsoma = false; %if the first segment is soma
        soma_diam %the diameter of the soma
    end
    
    methods (Access = private)
        
        function drawSkeleton(app)
            set(app.RGCMorphologyAnalyzerUIFigure, 'Name', 'loading skeleton ...');
            if isempty(app.nodes_flattened)
                [x,y,z] = ind2sub(size(app.skel), find(app.skel));
                x = x * app.xy_res;
                y = y * app.xy_res;
                z = z * app.z_res;
            else
                x = app.nodes_flattened(:,1);
                y = app.nodes_flattened(:,2);
                z = app.nodes_flattened(:,3);
            end
            
            %use scale factor for display
            z = z.*get(app.ZscalefactorEditField,'value');
            
            app.skel_h = scatter3(x,y,z,app.dotSize,'k','filled','Parent',app.skel_axes);
            xlabel(app.skel_axes,'x (µm)');
            ylabel(app.skel_axes,'y (µm)');
            %app.skel_h = plot3(app.skel_axes, x,y,z,'-.k','MarkerSize',app.dotSize);
            %skel_line = plot3(x,y,z,app.dotSize,'k-.','filled','Parent',app.skel_axes);
            axis(app.skel_axes,'equal');
            app.skel_axes.DataAspectRatio = [1 1 .2];
            axis(app.skel_axes, 'vis3d');
            set(app.RGCMorphologyAnalyzerUIFigure, 'Name', 'RGC Morphology Analyzer');
        end
        
        function colorSkeleton(app)
            set(app.RGCMorphologyAnalyzerUIFigure, 'Name', 'loading skeleton ...');
            if ~isempty(app.skel_color_vec)
                cmap = interp1(linspace(0,1,256), colormap('parula'), linspace(0, 1, 1+range(app.skel_color_vec)));
                set(app.skel_h, 'cdata', cmap(app.skel_color_vec, :));
                drawnow;
            end
            axis(app.skel_axes, 'vis3d');
            set(app.RGCMorphologyAnalyzerUIFigure, 'Name', 'RGC Morphology Analyzer');
        end
        
        function init(app)
            set(app.xyEditField,'value',app.xy_res);
            set(app.zEditField,'value',app.z_res);
            set(app.DotsizeSlider,'value',app.dotSize);
            set(app.SmoothingwindowmSlider,'value',app.filterWidth);
            app.updateSurfacesList();
            
            app.dcm = datacursormode(app.RGCMorphologyAnalyzerUIFigure);
            set(app.dcm,'UpdateFcn', @datatip_3d_func);
        end
        
        function drawSurface(app)
            set(app.RGCMorphologyAnalyzerUIFigure, 'Name', 'drawing surface ...');            
            [x,y] = meshgrid(1:app.h, 1:app.w);
            x = x * app.xy_res;
            y = y * app.xy_res;
            
            surfInd = find(strcmp(app.activeSurf, app.surfaces));
            z_filt = app.surfaceMaps{surfInd} * app.z_res;
            surf(app.surf_axes,x,y,z_filt,'edgecolor','interp');
            axis(app.surf_axes,'equal');
            app.surf_axes.DataAspectRatio = [1 1 .2];
            axis(app.surf_axes, 'vis3d');
            set(app.RGCMorphologyAnalyzerUIFigure, 'Name', 'RGC Morphology Analyzer');
        end
        
        function updateSurfacesList(app)
            set(app.SurfacesListBox, 'items', app.surfaces);    
            set(app.ListBox_surfaceSelector, 'items', app.surfaces);
            set(app.LowersurfaceDropDown, 'items', app.surfaces);
            set(app.UppersurfaceDropDown, 'items', ['fixed offset', app.surfaces]);
        end
        
        function computeDistancesToSurfPoints(app)
            set(app.RGCMorphologyAnalyzerUIFigure, 'Name', 'computing point distances ...');
            %get only surfaces with at least one data point defined
            surfList = {};
            
            for i=1:length(app.surfaces)
                app.surfaces{i};
                dt = findobj(app.skel_h,'type','datatip','tag',app.surfaces{i});
                if ~isempty(dt)
                    surfList = [surfList, app.surfaces{i}];
                    mask = false(size(app.skel));
                    for j=1:length(dt)
                        x = round(dt(j).X/app.xy_res);
                        y = round(dt(j).Y/app.xy_res);
                        z = round(dt(j).Z/app.z_res);
                        mask(x, y, z) = 1;
                    end
                    
                    distMap = bwdistgeodesic(app.skel, mask);
                    distVec(i,:) = distMap(~isnan(distMap));     
                end
            end
            if ~isempty(surfList)
                [~, minRow] = min(distVec,[],1);
                app.colored_surfaces = surfList;
                app.skel_color_vec = minRow;
                app.colorSkeleton();
            end
            
            set(app.RGCMorphologyAnalyzerUIFigure, 'Name', 'RGC Morphology Analyzer');
        end

        
        function matchPointsWithVisibleDatatips(app)
            dt = findobj(app.skel_h,'type','datatip', 'visible','on');
            if isempty(dt)
                dtX = [];
                dtY = [];
                dtZ = [];
            else
                Ndt = length(dt);
                dtX = zeros(1,Ndt);
                dtY = zeros(1,Ndt);
                dtZ = zeros(1,Ndt);
                for i=1:Ndt
                    dtX(i) = [dt(i).X];
                    dtY(i) = [dt(i).Y];
                    dtZ(i) = [dt(i).Z];
                end
            end
            red_spots = findobj(app.skel_axes,'type','scatter', 'Cdata', [1 0 0]);
            for i=1:length(red_spots)
                indx = find(dtX == red_spots(i).XData);
                indy = find(dtY == red_spots(i).YData);
                indz = find(dtZ == red_spots(i).ZData);
                thisInd = intersect(indz, intersect(indx, indy));
                if ~isempty(thisInd)
                   set(red_spots(i),'visible','on'); 
                else
                   set(red_spots(i),'visible','off'); 
                end
            end
        end
        
        function plotStrat(app)
            plot(app.Strat_axes, app.strat_x, app.strat_y_norm, 'k-', 'LineWidth',2);
            hold(app.Strat_axes,'on');
            xlim(app.Strat_axes,[-2 2])
            line(app.Strat_axes,[0 0], [0 1], 'Color','r','LineStyle','--');
            line(app.Strat_axes,[1 1], [0 1], 'Color','r','LineStyle','--');
            hold(app.Strat_axes,'off');

        end
        
        function populateResultsTable(app)
            

            if app.arborStats.bistratified
                 resultsTable = table('Size',[15,4],...
                'VariableNames', {'paramName', 'parameter','value','units'},...
                'VariableTypes', {'string', 'string', 'double', 'string'});
                
                resultsTable.paramName(1) = 'Nbranches';
                resultsTable.paramName(2) = 'totalLen';
                resultsTable.paramName(3) = 'totalLen_lower';
                resultsTable.paramName(4) = 'totalLen_upper';
                resultsTable.paramName(5) = 'polygonArea_lower';
                resultsTable.paramName(6) = 'polygonArea_upper';
                resultsTable.paramName(7) = 'arborDensity_lower';
                resultsTable.paramName(8) = 'arborDensity_upper';
                resultsTable.paramName(9) = 'arborComplexity';
                resultsTable.paramName(10) = 'convexityIndex_lower';
                resultsTable.paramName(11) = 'convexityIndex_upper';
                resultsTable.paramName(12) = 'branchLen_median';
                resultsTable.paramName(13) = 'branchAngle_median';
                resultsTable.paramName(14) = 'branchRangeZ_median';
                resultsTable.paramName(15) = 'branchTortuosity_median';
                
                resultsTable.parameter(1) = '# branches';
                resultsTable.parameter(2) = 'Total length';
                resultsTable.parameter(3) = 'Total length lower';
                resultsTable.parameter(4) = 'Total length upper';
                resultsTable.parameter(5) = 'Area lower';
                resultsTable.parameter(6) = 'Area upper';
                resultsTable.parameter(7) = 'Density lower';
                resultsTable.parameter(8) = 'Density upper';
                resultsTable.parameter(9) = 'Complexity';
                resultsTable.parameter(10) = 'Convexity index lower';
                resultsTable.parameter(11) = 'Convexity index upper';
                resultsTable.parameter(12) = 'Median len.';
                resultsTable.parameter(13) = 'Median angle';
                resultsTable.parameter(14) = 'Median z range';
                resultsTable.parameter(15) = 'Median tortuosity';
                
                resultsTable.units(1) = '';
                resultsTable.units(2) = 'μm';
                resultsTable.units(3) = 'μm';
                resultsTable.units(4) = 'μm';
                resultsTable.units(5) = 'μm^2';
                resultsTable.units(6) = 'μm^2';
                resultsTable.units(7) = 'branches/μm^2';
                resultsTable.units(8) = 'branches/μm^2';
                resultsTable.units(9) = '';
                resultsTable.units(10) = '';
                resultsTable.units(11) = '';
                resultsTable.units(12) = 'μm';
                resultsTable.units(13) = 'rad';
                resultsTable.units(14) = 'μm';
                resultsTable.units(15) = '';
            else
                resultsTable = table('Size',[10,4],...
                'VariableNames', {'paramName', 'parameter','value','units'},...
                'VariableTypes', {'string', 'string', 'double', 'string'});
                
                resultsTable.paramName(1) = 'Nbranches';
                resultsTable.paramName(2) = 'totalLen';
                resultsTable.paramName(3) = 'polygonArea';
                resultsTable.paramName(4) = 'arborDensity';
                resultsTable.paramName(5) = 'arborComplexity';
                resultsTable.paramName(6) = 'convexityIndex';
                resultsTable.paramName(7) = 'branchLen_median';
                resultsTable.paramName(8) = 'branchAngle_median';
                resultsTable.paramName(9) = 'branchRangeZ_median';
                resultsTable.paramName(10) = 'branchTortuosity_median';
                
                resultsTable.parameter(1) = '# branches';
                resultsTable.parameter(2) = 'Total length';
                resultsTable.parameter(3) = 'Area';
                resultsTable.parameter(4) = 'Density';
                resultsTable.parameter(5) = 'Complexity';
                resultsTable.parameter(6) = 'Convexity index';
                resultsTable.parameter(7) = 'Median len.';
                resultsTable.parameter(8) = 'Median angle';
                resultsTable.parameter(9) = 'Median z range';
                resultsTable.parameter(10) = 'Median tortuosity';
                
                resultsTable.units(1) = '';
                resultsTable.units(2) = 'μm';
                resultsTable.units(3) = 'μm^2';
                resultsTable.units(4) = 'branches/μm^2';
                resultsTable.units(5) = 'branches/μm';
                resultsTable.units(6) = '';
                resultsTable.units(7) = 'μm';
                resultsTable.units(8) = 'rad';
                resultsTable.units(9) = 'μm';
                resultsTable.units(10) = '';
            end
            
            
            S = app.arborStats;
            for i=1:height(resultsTable)
                param = resultsTable.paramName(i);
                if isfield(S,param)
                    resultsTable.value(i) = S.(param);
                elseif contains(param,'_median')
                    str_parts = strsplit(param,'_median');
                    p = str_parts{1};
                    resultsTable.value(i) = median(S.(p));
                else
                    sprintf('Parameter %s not found.\n', param);
                end
            end
            
            %resultsTable
            %strip off paramName column
            set(app.ResultsTable, 'Data', resultsTable(:,2:end));
            
        end
        
        function drawNodeLines(app)
            set(app.RGCMorphologyAnalyzerUIFigure, 'Name', 'loading node segments ...');
            
            hold(app.skel_axes,'on');
            N = length(app.nodes);
            for i=1:N-1
                app.segmentLines(i) = plot3(app.skel_axes, [app.nodes(app.edges(i,1),2),  app.nodes(app.edges(i,2),2)], ...
                    [app.nodes(app.edges(i,1),1),  app.nodes(app.edges(i,2),1)], ...
                    [app.nodes(app.edges(i,1),3),  app.nodes(app.edges(i,2),3)] .* get(app.ZscalefactorEditField,'value'), ...
                    'k');
                set(app.segmentLines(i),'visible',false);
            end
            hold(app.skel_axes,'off');
            %keyboard;
            set(app.RGCMorphologyAnalyzerUIFigure, 'Name', 'RGC Morphology Analyzer');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init();
            app.last_path = '.';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.RGCMorphologyAnalyzerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {635, 635, 635};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {635, 635};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {219, '1x', 350};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Menu selected function: LoadskeletonimageMenu
        function LoadskeletonimageMenuSelected(app, event)
            [fname, fpath] = uigetfile(sprintf('%s%s*.tif*', app.last_path, filesep),'Select image file');
            figure(app.RGCMorphologyAnalyzerUIFigure); % refocus the main window
            if fpath ~= 0
                app.last_path = fpath;
            end
            if fname
                app.skel_color_vec = [];
                app.colored_surfaces = {};
                fname_full = [fpath filesep fname];
                info = imfinfo(fname_full);
                app.Nframes = length(info);
                app.w = info(1).Width;
                app.h = info(1).Height;
                app.xy_res = 1./info(1).XResolution;
                desc = info(1).ImageDescription;
                try
                    a = strsplit(desc,'spacing=');
                    app.z_res = str2double(strtok(a{2}));
                catch
                    disp('z spacing not found in file');
                end
                im = false(app.h, app.w, app.Nframes);
                
                for i=1:app.Nframes
                    im(:,:,i) = logical(imread(fname_full, i));
                end
                app.skel = im;
                app.init();                
                app.drawSkeleton();
                %app.drawSurface();
            end            
        end

        % Value changed function: DotsizeSlider
        function DotsizeSliderValueChanged(app, event)
            app.dotSize = round(app.DotsizeSlider.Value);
            app.drawSkeleton();
        end

        % Value changed function: SmoothingwindowmSlider
        function SmoothingwindowmSliderValueChanged(app, event)
            app.filterWidth = round(app.SmoothingwindowmSlider.Value);
            app.drawSurface();
        end

        % Button down function: skel_axes
        function skel_axesButtonDown(app, event)

            %set surface axis to the same as this one
            set(app.surf_axes,'CameraViewAngle',get(app.skel_axes,'CameraViewAngle'));
            set(app.surf_axes,'Xlim',get(app.skel_axes,'Xlim'));
            set(app.surf_axes,'Ylim',get(app.skel_axes,'Ylim'));
            set(app.surf_axes,'Zlim',get(app.skel_axes,'Zlim'));
            set(app.surf_axes,'view',get(app.skel_axes,'view'));
            set(app.surf_axes,'CameraPosition',get(app.skel_axes,'CameraPosition'));   

        end

        % Button down function: surf_axes
        function surf_axesButtonDown(app, event)
            set(app.skel_axes,'CameraViewAngle',get(app.surf_axes,'CameraViewAngle'));
            set(app.skel_axes,'Xlim',get(app.surf_axes,'Xlim'));
            set(app.skel_axes,'Ylim',get(app.surf_axes,'Ylim'));
            set(app.skel_axes,'Zlim',get(app.surf_axes,'Zlim'));
            set(app.skel_axes,'view',get(app.surf_axes,'view'));
            set(app.skel_axes,'CameraPosition',get(app.surf_axes,'CameraPosition'));
        end

        % Value changed function: ListBox_surfaceSelector
        function ListBox_surfaceSelectorValueChanged(app, event)
            app.activeSurf = app.ListBox_surfaceSelector.Value;
            
            %change datatip visibility
            dt_all = findobj(app.skel_h,'type','datatip');            
            for i=1:length(dt_all)
                if strcmp(get(dt_all(i), 'tag'),  app.activeSurf)
                    set(dt_all(i),'visible','on');                    
                else
                    set(dt_all(i),'visible','off');
                end                
            end     
            app.matchPointsWithVisibleDatatips();
            
            %if surface exists, draw it
            surfInd = find(strcmp(app.activeSurf, app.surfaces));
            if ~isempty(app.surfaceMaps{surfInd})
                app.drawSurface();
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            curName = get(app.SurfName_EditField, 'value');
            if ~isempty(curName)
                app.surfaces = [app.surfaces, curName];
                app.surfaceMaps{end+1} = [];
            end
            app.updateSurfacesList();
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            selectedSurf = get(app.SurfacesListBox, 'value');
            app.surfaces = setdiff(app.surfaces, selectedSurf);
            app.updateSurfacesList();
        end

        % Button pushed function: SelectsurfaceguidepointsButton
        function SelectsurfaceguidepointsButtonPushed(app, event)
            set(app.dcm,'Enable', 'on'); 
            %ind = findPointsInROI(app.skel_pt_cloud,roi)
        end

        % Close request function: RGCMorphologyAnalyzerUIFigure
        function RGCMorphologyAnalyzerUIFigureCloseRequest(app, event)
            delete(app)            
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            app.RGCMorphologyAnalyzerUIFigureCloseRequest();
        end

        % Button pushed function: ComputedistancestosurfButton, 
        % ...and 1 other component
        function ComputepointsdistancesButtonPushed(app, event)
            app.computeDistancesToSurfPoints();
        end

        % Button pushed function: SavepointsinselectedsurfButton
        function SavepointsinselectedsurfButtonPushed(app, event)
            dt_visible = findobj(app.skel_h,'type','datatip','visible','on');
            for i=1:length(dt_visible)
                set(dt_visible(i),'tag',app.activeSurf);
            end            
        end

        % Button pushed function: ClearsurfaceguidepointsButton
        function ClearsurfaceguidepointsButtonPushed(app, event)
            dt_visible = findobj(app.skel_h,'type','datatip','visible','on');
            red_spots_visible = findobj(app.skel_axes,'type','scatter', 'Cdata', [1 0 0],'visible','on');
            delete(dt_visible);
            delete(red_spots_visible);
            %app.matchPointsWithVisibleDatatips();
        end

        % Callback function
        function SaveguidepointsMenuSelected(app, event)
            dt = findobj(app.skel_h,'type','datatip');
            N = length(dt);
            appdata.dt_tags = cell(N,1);
            appdata.dt_locations = zeros(N,3);
            
            for i=1:N
                appdata.dt_tags{i} = get(dt(i),'tag');
                appdata.dt_locations(i,1) = get(dt(i),'X');
                appdata.dt_locations(i,2) = get(dt(i),'Y');
                appdata.dt_locations(i,3) = get(dt(i),'Z');                
            end
            appdata.surfaces = app.surfaces;
            appdata.activeSurface = app.activeSurf;

%             [fname, fpath] = uiputfile(sprintf('%s%s*.mat',app.last_path,filesep),'Save guide point data...','guide_points.mat');
            [fname, fpath] = uiputfile('*.mat','Save guide point data...',sprintf('%s%sguide_points.mat',app.last_path,filesep));
            figure(app.RGCMorphologyAnalyzerUIFigure); % refocus the main window
            if fpath ~= 0
                app.last_path = fpath;
            end
            if fname
                save([fpath filesep fname], 'appdata');
            end
        end

        % Callback function
        function LoadguidepointsMenuSelected(app, event)
%             [fname, fpath] = uigetfile();
            [fname, fpath] = uigetfile(app.last_path);
            figure(app.RGCMorphologyAnalyzerUIFigure); % refocus the main window
            if fpath ~= 0
                app.last_path = fpath;
            end
%             dtt = get(app.skel_h,'datatipTemplate')
%             set(dtt,'DataTipRows',dataTipTextRow('dummy',''));
            if fname
                load([fpath filesep fname], 'appdata');
                app.surfaces = appdata.surfaces;
                app.activeSurf = appdata.activeSurface;
                set(app.SurfacesListBox,'items',app.surfaces);
                set(app.ListBox_surfaceSelector, 'items', app.surfaces);
                set(app.ListBox_surfaceSelector, 'value', app.activeSurf);
                N = length(appdata.dt_tags);
                for i=1:N
                    info.Position = appdata.dt_locations(i,:);
                    info.Target = app.skel_h;
%                     info
                    % dt = datatip(app.skel_h, 'CreateFcn', ...
                    %     {@datatip_3d_create info});
                     dt = datatip(app.skel_h,...
                        appdata.dt_locations(i,1),...
                        appdata.dt_locations(i,2), ...
                        appdata.dt_locations(i,3));
                    set(dt, 'tag', appdata.dt_tags{i})                    
                end
                dt_all = findobj(app.skel_h,'type','datatip');
                for i=1:length(dt_all)
                    if strcmp(get(dt_all(i), 'tag'),  app.activeSurf)
                        set(dt_all(i),'visible','on');
                    else
                        set(dt_all(i),'visible','off');
                    end
                end
                app.matchPointsWithVisibleDatatips();
                app.computeDistancesToSurfPoints();
                app.updateSurfacesList();
            end
        end

        % Button pushed function: MakesurfaceButton
        function MakesurfaceButtonPushed(app, event)
            set(app.RGCMorphologyAnalyzerUIFigure, 'Name', 'computing surface ...');
            filterW_pix = round(app.filterWidth / app.xy_res);
            
            Z_strat = zeros(app.h,app.w);
            
            surfInd = find(strcmp(app.activeSurf, app.surfaces));
            mask = false(size(app.skel));
            surfPoints = app.skel_color_vec == surfInd;
            [x,y,z] = ind2sub(size(app.skel), find(app.skel));
            x = x(surfPoints);
            y = y(surfPoints);
            z = z(surfPoints);
            mask(sub2ind(size(mask), x,y,z)) = 1;
            
            for i=1:app.h
                for j=1:app.w
                    firstSpot = find(squeeze(mask(i,j,:)), 1);
                    if isempty(firstSpot)
                        Z_strat(i,j) = nan;
                    else
                        Z_strat(i,j) = firstSpot;
                    end
                end
            end
            
            app.surfaceMaps{surfInd} = ndnanfilter(Z_strat,@chebwin,[filterW_pix filterW_pix])';
            set(app.RGCMorphologyAnalyzerUIFigure, 'Name', 'RGC Morphology Analyzer');
            app.drawSurface();
        end

        % Button pushed function: UnwarpButton
        function UnwarpButtonPushed(app, event)
            set(app.RGCMorphologyAnalyzerUIFigure, 'Name', 'computing warped arbor ...');


            conformalJump = 1;
            
            lower_surf_name = get(app.LowersurfaceDropDown, 'value');
            surfInd = find(strcmp(lower_surf_name, app.surfaces));
            lower_surf = app.surfaceMaps{surfInd};
            if isempty(lower_surf)
                sprintf('Surface %s not computed', lower_surf_name);                
                return;
            end
            
            upper_surf_name = get(app.UppersurfaceDropDown, 'value');
            if strcmp(upper_surf_name, 'fixed offset')
                upper_surf = lower_surf + get(app.OffsetEditField, 'value');
                %upper_surf = [];
            else
                surfInd = find(strcmp(upper_surf_name, app.surfaces));
                upper_surf = app.surfaceMaps{surfInd};
                if isempty(upper_surf)
                    sprintf('Surface %s not computed', upper_surf_name);
                end
            end
                   
            if isempty(app.nodes)
                disp('SWC file must be loaded before unwarping');
                return;
            end
            
            %upper_surf = lower_surf;
            %lower_surf = ones(size(lower_surf));
            %upper_surf = ones(size(lower_surf)) * (nanmean(nanmean(lower_surf)) + get(app.OffsetEditField, 'value'));
            %keyboard;
            
            arborBoundaries(1) = min(app.nodes(:,1)); arborBoundaries(2) = max(app.nodes(:,1));
            arborBoundaries(3) = min(app.nodes(:,2)); arborBoundaries(4) = max(app.nodes(:,2));
            %arborBoundaries(1) = min(app.nodes(:,2)); arborBoundaries(2) = max(app.nodes(:,2));
            %arborBoundaries(3) = min(app.nodes(:,1)); arborBoundaries(4) = max(app.nodes(:,1));
            
            [Xvals, Yvals] = meshgrid([1:app.w] * app.xy_res, [1:app.h] * app.xy_res);
            X_flat = reshape(Xvals', [], 1);
            Y_flat = reshape(Yvals', [], 1);
            
            Z_flat_lower = reshape(lower_surf, 1, []);
            if ~isempty(upper_surf)
                Z_flat_upper = reshape(upper_surf, 1, []);
            end

            %keyboard;
            thisVZminmesh = fitSurfaceToSACAnnotation_fromPoints(X_flat,Y_flat,Z_flat_lower);
            %thisVZminmesh = fitSurfaceToSACAnnotation_fromPoints(Y_flat,X_flat,Z_flat_lower);

            thisVZminmesh = thisVZminmesh*app.z_res;
          
            if ~isempty(upper_surf)
                thisVZmaxmesh = fitSurfaceToSACAnnotation_fromPoints(X_flat,Y_flat,Z_flat_upper);
                %thisVZmaxmesh = fitSurfaceToSACAnnotation_fromPoints(Y_flat,X_flat,Z_flat_upper);

                thisVZmaxmesh = thisVZmaxmesh*app.z_res;
            end
            
            if ~isempty(upper_surf)
                surfaceMapping = calcWarpedSACsurfaces(thisVZminmesh,thisVZmaxmesh,arborBoundaries,conformalJump);
                warpedArbor = calcWarpedArbor(app.nodes,app.edges,app.radii,surfaceMapping);
                app.upper_surface_z = warpedArbor.medVZmax;
            else
                surfaceMapping = calcWarpedSurface_single(thisVZminmesh,arborBoundaries,conformalJump);
                warpedArbor = calcWarpedArbor_single(app.nodes,app.edges,app.radii,surfaceMapping);
            end
            if sum(isnan(warpedArbor.nodes),'all')
                disp('error: NaN nodes. Check if .swc has disconnected segments');
            end
            app.lower_surface_z = warpedArbor.medVZmin;
            app.nodes_flattened = warpedArbor.nodes;
            app.edges_flattened = warpedArbor.edges;
            app.radii_flattened = warpedArbor.radii;
            app.lower_surf_norm_z = get(app.NormposEditField_lower, 'value');
            app.upper_surf_norm_z = get(app.NormposEditField_upper,'value');            
            set(app.RGCMorphologyAnalyzerUIFigure, 'Name', 'RGC Morphology Analyzer');
            
            app.drawSkeleton();
            hold(app.skel_axes,'on')
            colormap(app.skel_axes,'parula');
            surf(app.skel_axes,Xvals,Yvals,ones(size(Xvals)).*app.lower_surface_z,'FaceAlpha',0.2,'FaceColor','r');
            surf(app.skel_axes,Xvals,Yvals,ones(size(Xvals)).*app.upper_surface_z,'FaceAlpha',0.2,'FaceColor','r');            
            hold(app.skel_axes,'off')
        end

        % Menu selected function: LoadswcMenu
        function LoadswcMenuSelected(app, event)
%             [fname, fpath] = uigetfile('*/*.swc','Select swc file');
            [fname, fpath] = uigetfile(sprintf('%s%s*.swc',app.last_path,filesep),'Select swc file');
            figure(app.RGCMorphologyAnalyzerUIFigure); % refocus the main window
            if fpath ~= 0
                app.last_path = fpath;
            end
            if fname
                [nodes,edges,radii,nodeTypes,abort,somasizepix] = readArborTrace([fpath filesep fname],[-1 0 1 2 3 4 5]);
                nodes = nodes + 1;
                app.nodes = nodes;
                app.edges = edges;
                app.radii = radii;

                app.soma_diam = somasizepix;
                app.drawNodeLines();
            end
            
        end

        % Value changed function: UppersurfaceDropDown
        function UppersurfaceDropDownValueChanged(app, event)
            value = app.UppersurfaceDropDown.Value;
            if strcmp(value, 'fixed offset')
                set(app.OffsetEditField, 'visible', true);
                set(app.OffsetmEditFieldLabel, 'visible', true)
            else
                set(app.OffsetEditField, 'visible', false);
                set(app.OffsetmEditFieldLabel, 'visible', false)
            end
        end

        % Button pushed function: ReloadoriginalButton
        function ReloadoriginalButtonPushed(app, event)
            app.nodes_flattened = [];
            app.edges_flattened = [];
            app.radii_flattened = [];
            app.drawSkeleton();
        end

        % Menu selected function: StratificationprofileMenu
        function StratificationprofileMenuSelected(app, event)
            if ~isempty(app.nodes_flattened)
                Nbins = 101; %can make a parameter
                [app.strat_x, app.strat_density, app.allXYpos_flattened, app.allZpos_flattened, app.length_by_node] = calc3dDist(app.nodes_flattened,app.edges,app.lower_surface_z,app.upper_surface_z, Nbins);
                %strat_y = strat_density*x_microns; %now in units of microns length
                app.strat_y_norm = app.strat_density./max(app.strat_density);   
                app.plotStrat();
                %keyboard;
            else
                disp('flattened arbor not computed');
            end
        end

        % Menu selected function: ArborpropertiesMenu
        function ArborpropertiesMenuSelected(app, event)
            if ~isempty(app.allXYpos_flattened)
                set(app.RGCMorphologyAnalyzerUIFigure, 'Name', 'computing arbor stats...');
                S.edges = app.edges_flattened;
                S.allXYpos = app.allXYpos_flattened;
                S.allZpos = app.allZpos_flattened;
                
                 %TODO find ON-OFF separation point and do this separatelly for each
                 if get(app.BistratifiedCheckBox,'Value')
                     S.bistratified = true;
                     %splitPoint = mean([app.lower_surface_z, app.upper_surface_z])
                     allXYpos_lower = app.allXYpos_flattened(app.allZpos_flattened < 0.5, :);
                     allXYpos_upper = app.allXYpos_flattened(app.allZpos_flattened > 0.5, :);
                     
                     total_length_lower = sum(app.length_by_node(app.allZpos_flattened < 0.5));
                     total_length_upper = sum(app.length_by_node(app.allZpos_flattened > 0.5));
%keyboard;
                     [boundaryPoints, polygonArea] = boundary(allXYpos_lower(:,1), allXYpos_lower(:,2), 1);
                     [~, polygonArea_convex] = boundary(allXYpos_lower(:,1), allXYpos_lower(:,2), 0);
                     S.polygonArea_lower = polygonArea;
                     S.boundaryPoints_lower = boundaryPoints;
                     S.convexityIndex_lower = polygonArea_convex ./ polygonArea;
                    
                     
                     [boundaryPoints, polygonArea] = boundary(allXYpos_upper(:,1), allXYpos_upper(:,2), 1);
                     [~, polygonArea_convex] = boundary(allXYpos_upper(:,1), allXYpos_upper(:,2), 0);
                     S.polygonArea_upper = polygonArea;
                     S.boundaryPoints_upper = boundaryPoints;
                     S.convexityIndex_upper = polygonArea_convex ./ polygonArea;
                     
                 else
                     S.bistratified = false;
                     
                     [boundaryPoints, polygonArea] = boundary(S.allXYpos(:,1), S.allXYpos(:,2), 1);
                     [~, polygonArea_convex] = boundary(S.allXYpos(:,1), S.allXYpos(:,2), 0);
                     S.polygonArea = polygonArea;
                     S.boundaryPoints = boundaryPoints;
                     S.convexityIndex = polygonArea_convex ./ polygonArea;
                     
                 end
                 
                 R = morphologyParams(S);
                 
                 if S.bistratified
                      R.totalLen_lower = total_length_lower;
                      R.totalLen_upper = total_length_upper;
                      R.arborDensity_lower =  total_length_lower / S.polygonArea_lower; 
                      R.arborDensity_upper =  total_length_upper / S.polygonArea_upper;                     
                 else
                     R.arborDensity =  R.totalLen / S.polygonArea;
                 end

               
                 app.arborStats = mergeStruct(R, S);
                 app.populateResultsTable();
                 disp('done computing arbor stats')
                 set(app.RGCMorphologyAnalyzerUIFigure, 'Name', 'RGC Morphology Analyzer');
             else
                 disp('stratification profile must be computed first')
             end
             
        end

        % Button pushed function: DrawsurfaceonskeletonButton
        function DrawsurfaceonskeletonButtonPushed(app, event)
            set(app.RGCMorphologyAnalyzerUIFigure, 'Name', 'drawing surface ...');            
            [x,y] = meshgrid(1:app.h, 1:app.w);
            x = x * app.xy_res;
            y = y * app.xy_res;
            
            surfInd = find(strcmp(app.activeSurf, app.surfaces));
            z_filt = app.surfaceMaps{surfInd} * app.z_res;
            hold(app.skel_axes, 'on');
            surf(app.skel_axes,x,y,z_filt,'edgecolor','flat','FaceAlpha',0.1,'EdgeAlpha',0.1);
            hold(app.skel_axes, 'off');
            set(app.RGCMorphologyAnalyzerUIFigure, 'Name', 'RGC Morphology Analyzer');
        end

        % Menu selected function: SavesurfacesMenu
        function SavesurfacesMenuSelected(app, event)
            [fname, fpath] = uiputfile('*.mat','Save surfaces file',sprintf('%s%ssurfaces.mat',app.last_path,filesep));
            figure(app.RGCMorphologyAnalyzerUIFigure); % refocus the main window
            if fpath ~= 0
                app.last_path = fpath;
            end
            if fname
                appdata.surfaces = app.surfaces;
                appdata.skel_color_vec = app.skel_color_vec;
                appdata.colored_surfaces = app.colored_surfaces;
                appdata.surfaceMaps = app.surfaceMaps;
                
                save([fpath filesep fname], 'appdata');
            end
        end

        % Menu selected function: LoadsurfacesMenu
        function LoadsurfacesMenuSelected(app, event)
            [fname, fpath] = uigetfile(sprintf('%s%s*.mat',app.last_path,filesep),'Load surfaces file');
            figure(app.RGCMorphologyAnalyzerUIFigure); % refocus the main window
            if fpath ~= 0
                app.last_path = fpath;
            end
            if fname
                load([fpath filesep fname], 'appdata');
                app.surfaces = appdata.surfaces;
                app.updateSurfacesList();
                app.ListBox_surfaceSelector.Value = app.surfaces{1};
                app.activeSurf = app.surfaces{1};
                %app.skel_color_vec = appdata.skel_color_vec;
                %app.colored_surfaces = appdata.colored_surfaces;
                app.surfaceMaps = appdata.surfaceMaps;
                app.drawSurface();
            end
        end

        % Menu selected function: LoadanalyzeddataMenu
        function LoadanalyzeddataMenuSelected(app, event)
            [fname, fpath] = uigetfile(sprintf('%s%s*.mat', app.last_path, filesep),'Load analyzed data file','arborData.mat');
            figure(app.RGCMorphologyAnalyzerUIFigure); % refocus the main window
            if fpath ~= 0
                app.last_path = fpath;
            end
            if fname
                load([fpath filesep fname], 'appdata');
                app.nodes_flattened = appdata.nodes_flattened;
                app.edges_flattened = appdata.edges_flattened;
                app.radii_flattened = appdata.radii_flattened;
                app.lower_surface_z = appdata.lower_surface_z;
                app.upper_surface_z = appdata.upper_surface_z;
                app.lower_surf_norm_z = appdata.lower_surf_norm_z;
                app.upper_surf_norm_z = appdata.upper_surf_norm_z;
                app.strat_x = appdata.strat_x;
                app.strat_density = appdata.strat_density;
                app.strat_y_norm = appdata.strat_y_norm;
                app.arborStats = appdata.arborStats;
                if(isfield(appdata, 'soma_size'))
                    app.soma_diam = appdata.soma_size;
                 end
                app.plotStrat();
                app.populateResultsTable();
            end

        end

        % Menu selected function: SaveanalyzeddataMenu
        function SaveanalyzeddataMenuSelected(app, event)
            [fname, fpath] = uiputfile('*.mat','Save analyzed data file',sprintf('%s%sarborData.mat',app.last_path, filesep));
            figure(app.RGCMorphologyAnalyzerUIFigure); % refocus the main window
            if fpath ~= 0
                app.last_path = fpath;
            end
            if fname
                appdata.nodes_flattened = app.nodes_flattened;
                appdata.edges_flattened = app.edges_flattened;
                appdata.radii_flattened = app.radii_flattened;
                appdata.lower_surface_z = app.lower_surface_z;
                appdata.upper_surface_z = app.upper_surface_z;
                appdata.lower_surf_norm_z = app.lower_surf_norm_z;
                appdata.upper_surf_norm_z = app.upper_surf_norm_z;
                appdata.strat_x = app.strat_x;
                appdata.strat_density = app.strat_density;
                appdata.strat_y_norm = app.strat_y_norm;
                appdata.arborStats = app.arborStats;
                %New feature: adding the first segement as the soma
                if (app.ifsoma)
                    appdata.soma_size = app.soma_diam;
                    fprintf('Soma size: %f micron\n', app.soma_diam);
                else
                    fprintf('Soma size not included in morph analysis.\n');
                    app.soma_size = nan;
                end
                save([fpath filesep fname], 'appdata');
            end
        end

        % Value changed function: SegmentLinesCheckBox
        function SegmentLinesCheckBoxValueChanged(app, event)
            value = app.SegmentLinesCheckBox.Value;
            %keyboard;
            if value
                for i=1:length(app.segmentLines)
                    set(app.segmentLines(i),'visible',true)
                end
            else
                for i=1:length(app.segmentLines)
                    set(app.segmentLines(i),'visible',false)
                end
            end
        end

        % Value changed function: ZscalefactorEditField
        function ZscalefactorEditFieldValueChanged(app, event)
            app.drawSkeleton;
        end

        % Value changed function: FirstSegSoma
        function FirstSegSomaValueChanged(app, event)
            app.ifsoma = app.FirstSegSoma.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RGCMorphologyAnalyzerUIFigure = uifigure('Visible', 'off');
            app.RGCMorphologyAnalyzerUIFigure.Name = 'RGC Morphology Analyzer';
            app.RGCMorphologyAnalyzerUIFigure.CloseRequestFcn = createCallbackFcn(app, @RGCMorphologyAnalyzerUIFigureCloseRequest, true);
            app.RGCMorphologyAnalyzerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.RGCMorphologyAnalyzerUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadskeletonimageMenu = uimenu(app.FileMenu);
            app.LoadskeletonimageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadskeletonimageMenuSelected, true);
            app.LoadskeletonimageMenu.Text = 'Load skeleton image';
            app.LoadswcMenu = uimenu(app.FileMenu);
            app.LoadswcMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadswcMenuSelected, true);
            app.LoadswcMenu.Text = 'Load .swc';
            app.LoadsurfacesMenu = uimenu(app.FileMenu);
            app.LoadsurfacesMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadsurfacesMenuSelected, true);
            app.LoadsurfacesMenu.Text = 'Load surfaces';
            app.LoadanalyzeddataMenu = uimenu(app.FileMenu);
            app.LoadanalyzeddataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadanalyzeddataMenuSelected, true);
            app.LoadanalyzeddataMenu.Text = 'Load analyzed data';
            app.SavesurfacesMenu = uimenu(app.FileMenu);
            app.SavesurfacesMenu.MenuSelectedFcn = createCallbackFcn(app, @SavesurfacesMenuSelected, true);
            app.SavesurfacesMenu.Text = 'Save surfaces';
            app.SaveanalyzeddataMenu = uimenu(app.FileMenu);
            app.SaveanalyzeddataMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveanalyzeddataMenuSelected, true);
            app.SaveanalyzeddataMenu.Text = 'Save analyzed data';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.AnalyzeMenu = uimenu(app.RGCMorphologyAnalyzerUIFigure);
            app.AnalyzeMenu.Text = 'Analyze';
            app.StratificationprofileMenu = uimenu(app.AnalyzeMenu);
            app.StratificationprofileMenu.MenuSelectedFcn = createCallbackFcn(app, @StratificationprofileMenuSelected, true);
            app.StratificationprofileMenu.Text = 'Stratification profile';
            app.ArborpropertiesMenu = uimenu(app.AnalyzeMenu);
            app.ArborpropertiesMenu.MenuSelectedFcn = createCallbackFcn(app, @ArborpropertiesMenuSelected, true);
            app.ArborpropertiesMenu.Text = 'Arbor properties';
            app.GridLayout = uigridlayout(app.RGCMorphologyAnalyzerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SurfacesListBoxLabel = uilabel(app.LeftPanel);
            app.SurfacesListBoxLabel.Text = 'Surfaces';
            app.SurfacesListBox = uilistbox(app.LeftPanel);
            app.SurfacesListBox.Items = {'Dendrites_1'};
            app.SurfacesListBox.Value = 'Dendrites_1';
            app.SmoothingwindowmSliderLabel = uilabel(app.LeftPanel);
            app.SmoothingwindowmSliderLabel.Text = 'Smoothing window (μm)';
            app.SmoothingwindowmSlider = uislider(app.LeftPanel);
            app.SmoothingwindowmSlider.Limits = [5 80];
            app.SmoothingwindowmSlider.ValueChangedFcn = createCallbackFcn(app, @SmoothingwindowmSliderValueChanged, true);
            app.SmoothingwindowmSlider.MinorTicks = [5 7.5 10 12.5 15 17.5 20 22.5 25 27.5 30 32.5 35 37.5 40 42.5 45 47.5 50 52.5 55 57.5 60 62.5 65 67.5 70 72.5 75 77.5 80];
            app.SmoothingwindowmSlider.Value = 65;
            app.SomacenterframeEditFieldLabel = uilabel(app.LeftPanel);
            app.SomacenterframeEditFieldLabel.Text = 'Soma center frame';
            app.SomacenterframeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.xyEditFieldLabel = uilabel(app.LeftPanel);
            app.xyEditFieldLabel.Text = 'xy';
            app.xyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.zEditFieldLabel = uilabel(app.LeftPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ScalempixelLabel = uilabel(app.LeftPanel);
            app.ScalempixelLabel.Text = 'Scale (μm / pixel)';
            app.AddButton = uibutton(app.LeftPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.LeftPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.DotsizeSliderLabel = uilabel(app.LeftPanel);
            app.DotsizeSliderLabel.Text = 'Dot size';
            app.DotsizeSlider = uislider(app.LeftPanel);
            app.DotsizeSlider.Limits = [1 21];
            app.DotsizeSlider.ValueChangedFcn = createCallbackFcn(app, @DotsizeSliderValueChanged, true);
            app.DotsizeSlider.MinorTicks = [1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10 10.5 11 11.5 12 12.5 13 13.5 14 14.5 15 15.5 16 16.5 17 17.5 18 18.5 19 19.5 20 20.5 21];
            app.DotsizeSlider.Value = 1;
            app.SurfName_EditField = uieditfield(app.LeftPanel, 'text');
            app.BistratifiedCheckBox = uicheckbox(app.LeftPanel);
            app.BistratifiedCheckBox.Text = 'Bistratified';
            app.SegmentLinesCheckBox = uicheckbox(app.LeftPanel);
            app.SegmentLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentLinesCheckBoxValueChanged, true);
            app.SegmentLinesCheckBox.Text = 'Segment Lines';
            app.ZscalefactorEditFieldLabel = uilabel(app.LeftPanel);
            app.ZscalefactorEditFieldLabel.Text = 'Z scale factor';
            app.ZscalefactorEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ZscalefactorEditField.Limits = [1 5];
            app.ZscalefactorEditField.ValueChangedFcn = createCallbackFcn(app, @ZscalefactorEditFieldValueChanged, true);
            app.ZscalefactorEditField.Value = 1;
            app.FirstSegSoma = uicheckbox(app.LeftPanel);
            app.FirstSegSoma.ValueChangedFcn = createCallbackFcn(app, @FirstSegSomaValueChanged, true);
            app.FirstSegSoma.Text = 'First segment is soma?';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.surf_axes = uiaxes(app.GridLayout2);
            title(app.surf_axes, 'Surface')
            xlabel(app.surf_axes, 'μm')
            ylabel(app.surf_axes, 'μm')
            zlabel(app.surf_axes, 'μm')
            app.surf_axes.ButtonDownFcn = createCallbackFcn(app, @surf_axesButtonDown, true);
            app.skel_axes = uiaxes(app.GridLayout2);
            title(app.skel_axes, 'Skeleton')
            xlabel(app.skel_axes, 'μm')
            ylabel(app.skel_axes, 'μm')
            zlabel(app.skel_axes, 'μm')
            app.skel_axes.ButtonDownFcn = createCallbackFcn(app, @skel_axesButtonDown, true);
            app.UnwarpButton = uibutton(app.GridLayout2, 'push');
            app.UnwarpButton.ButtonPushedFcn = createCallbackFcn(app, @UnwarpButtonPushed, true);
            app.UnwarpButton.Text = 'Unwarp';
            app.ReloadoriginalButton = uibutton(app.GridLayout2, 'push');
            app.ReloadoriginalButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadoriginalButtonPushed, true);
            app.ReloadoriginalButton.Text = 'Reload original';
            app.ListBox_surfaceSelector = uilistbox(app.GridLayout2);
            app.ListBox_surfaceSelector.ValueChangedFcn = createCallbackFcn(app, @ListBox_surfaceSelectorValueChanged, true);
            app.SelectsurfaceguidepointsButton = uibutton(app.GridLayout2, 'push');
            app.SelectsurfaceguidepointsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectsurfaceguidepointsButtonPushed, true);
            app.SelectsurfaceguidepointsButton.Text = 'Select surface guide points';
            app.ClearsurfaceguidepointsButton = uibutton(app.GridLayout2, 'push');
            app.ClearsurfaceguidepointsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearsurfaceguidepointsButtonPushed, true);
            app.ClearsurfaceguidepointsButton.Text = 'Clear surface guide points';
            app.SurfacesLabel = uilabel(app.GridLayout2);
            app.SurfacesLabel.Text = 'Surfaces';
            app.ComputepointsdistancesButton = uibutton(app.GridLayout2, 'push');
            app.ComputepointsdistancesButton.ButtonPushedFcn = createCallbackFcn(app, @ComputepointsdistancesButtonPushed, true);
            app.ComputepointsdistancesButton.Text = 'Compute points distances';
            app.SavepointsinselectedsurfButton = uibutton(app.GridLayout2, 'push');
            app.SavepointsinselectedsurfButton.ButtonPushedFcn = createCallbackFcn(app, @SavepointsinselectedsurfButtonPushed, true);
            app.SavepointsinselectedsurfButton.Text = 'Save points in selected surf.';
            app.MakesurfaceButton = uibutton(app.GridLayout2, 'push');
            app.MakesurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @MakesurfaceButtonPushed, true);
            app.MakesurfaceButton.Text = 'Make surface';
            app.DrawsurfaceonskeletonButton = uibutton(app.GridLayout2, 'push');
            app.DrawsurfaceonskeletonButton.ButtonPushedFcn = createCallbackFcn(app, @DrawsurfaceonskeletonButtonPushed, true);
            app.DrawsurfaceonskeletonButton.Text = 'Draw surface on skeleton ';
            app.LowersurfaceDropDownLabel = uilabel(app.GridLayout2);
            app.LowersurfaceDropDownLabel.Text = 'Lower surface';
            app.LowersurfaceDropDown = uidropdown(app.GridLayout2);
            app.UppersurfaceDropDownLabel = uilabel(app.GridLayout2);
            app.UppersurfaceDropDownLabel.Text = 'Upper surface';
            app.UppersurfaceDropDown = uidropdown(app.GridLayout2);
            app.UppersurfaceDropDown.ValueChangedFcn = createCallbackFcn(app, @UppersurfaceDropDownValueChanged, true);
            app.NormposEditFieldLabel = uilabel(app.GridLayout2);
            app.NormposEditFieldLabel.Text = 'Norm. pos.';
            app.NormposEditField_lower = uieditfield(app.GridLayout2, 'numeric');
            app.NormposEditField_lower.Value = Inf;
            app.NormposEditField_2Label = uilabel(app.GridLayout2);
            app.NormposEditField_2Label.Text = 'Norm. pos.';
            app.NormposEditField_upper = uieditfield(app.GridLayout2, 'numeric');
            app.NormposEditField_upper.Value = Inf;
            app.OffsetmEditFieldLabel = uilabel(app.GridLayout2);
            app.OffsetmEditFieldLabel.Text = 'Offset (μm)';
            app.OffsetEditField = uieditfield(app.GridLayout2, 'numeric');
            app.OffsetEditField.Value = 5;
            app.ComputedistancestosurfButton = uibutton(app.GridLayout2, 'push');
            app.ComputedistancestosurfButton.ButtonPushedFcn = createCallbackFcn(app, @ComputepointsdistancesButtonPushed, true);
            app.ComputedistancestosurfButton.Text = 'Compute distances to surf.';
            app.RightPanel = uipanel(app.GridLayout);
            app.Strat_axes = uiaxes(app.RightPanel);
            title(app.Strat_axes, 'Stratification')
            xlabel(app.Strat_axes, 'Depth')
            zlabel(app.Strat_axes, 'Z')
            app.ResultsTable = uitable(app.RightPanel);
            app.ResultsTable.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.ResultsTable.RowName = {};
            % Show the figure after all components are created
            app.RGCMorphologyAnalyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RGCMorphologyAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RGCMorphologyAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RGCMorphologyAnalyzerUIFigure)
        end
    end
end